classdef signals_project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        EditField_2        matlab.ui.control.EditField
        status             matlab.ui.control.EditField
        Lamp               matlab.ui.control.Lamp
        EditField          matlab.ui.control.EditField
        TabGroup           matlab.ui.container.TabGroup
        VoiceTab           matlab.ui.container.Tab
        voice              matlab.ui.control.UIAxes
        SpectrumTab        matlab.ui.container.Tab
        spec               matlab.ui.control.UIAxes
        FilterTab          matlab.ui.container.Tab
        filter_mag         matlab.ui.control.UIAxes
        StartRecordButton  matlab.ui.control.Button
    end

    
    properties (Access = private)

        %initiating feature and label matrix which will be used later
    end
    
    methods (Access = private)
        
        function feat_matrix = find_features(app,segments,Fs)
            [r,c] = size(segments);
            pitch = zeros(r,1);
            for i = 1:r
                % Compute the autocorrelation function
                temp = segments(i,:);
                autocorr_func = xcorr(temp);
                % Find the peaks in the autocorrelation function
                [peaks, peak_locs] = findpeaks(autocorr_func);
                % Find the peak with the highest amplitude (excluding the first peak, which corresponds to zero lag)
                [~, max_peak_idx] = max(peaks(2:end));
                % Compute the lag corresponding to the peak
                lag = peak_locs(max_peak_idx + 1) - peak_locs(1); % Add 1 to account for excluding the first peak
                % Compute the fundamental frequency (F0) in Hz
                F0 = Fs / lag;
                pitch(i) = F0;
            end

            format long
            Tn = 1./pitch;
            jitt = zeros(r,1);
            for i = 1:r-1
                temp = (1/r-1)*(Tn(i) - Tn(i+1))*100; %expressed as a percentage
                jitt(i+1) = temp;
            end
            jitt = abs(jitt);

            shim = zeros(r,1);
            temp_shim = zeros(r,1);
            for i = 1:r
                temp = segments(i,:);
                for j = 1:c-1
                    temp_shim(j+1) = (temp(j) - temp(j+1))/temp(j);
                    temp_shim(j+1) = abs(temp_shim(j+1));
                end
                sump = cumsum(temp_shim);
                sump = sump(end);

                shim(i) = (1/c-1)*sump*100;
            end
            shim = abs(shim);

            xa = zeros(1,c);
            for i = 1:c
                xa(i) = mean(segments(:,i));
            end
            xa_sqr = xa.^2;
            H = r*cumsum(xa_sqr);
            H = H(end); % harmonic component
            noise_comp = 0;
            for i = 1:r
                temp = segments(i,:) - xa;
                temp =  temp.^2;
                temp = cumsum(temp);
                noise_comp = noise_comp + temp(end);
            end
            N = noise_comp;
            HNR = 10 * log10(H/N) + zeros(r,1);
            feat_matrix=[pitch,jitt,shim,HNR];
            %making the label matrix
            %label=1; %0 for normal & 1 for parkinson
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.EditField.Value = 'Sustain the phonation of a vowel to test for Parkinsons for 5s';
        end

        % Button pushed function: StartRecordButton
        function StartRecordButtonPushed(app, event)

            load('binary_tester_mdlv1.mat');
            Fs=44100;
            app.Lamp.Color = 'r';
            app.status.Value = 'Recording';
            %app.LampLabel.Text = 'Recording';
            rec=audiorecorder(Fs,16,1);
            recordblocking(rec,5);
            app.Lamp.Color = 'g';
            app.status.Value = 'Finished';
            %app.LampLabel.Text = 'Finished';
            data=getaudiodata(rec);
            if size(data,1)>1 && size(data,2)>=1
                data=data(:,1);%making sure only one channel is getting into data
                data=data';
            end
            plot(app.voice,data)

            L = length(data);
            NFFT = 2^nextpow2(L);
            y_fft = abs(fft(data,NFFT));
            % creating frequency axis
            freq = Fs/2*linspace(0,1,NFFT/2+1);
            % Plot single-sided amplitude spectrum.
            plot(app.spec,y_fft(1:NFFT/2+1))


            fc = 10^4; % Cutoff frequency
            o = 5; % Order
            wn = fc / (Fs/2); % Normalized cutoff frequency
            [b,a] = butter(o, wn, 'low');
            % See frequency response of the filter
            [h,~] = freqz(b, a, 1024, Fs);
            %app.filter_mag = w;
            plot(app.filter_mag, 20*log10(abs(h)))

            %beginning of segmentation process
            data_filtered= filter(b,a,data);
            data=data_filtered;

            %segmenting
            %output is in segments variable

            segment_duration=2.5; %seconds
            len_segment = fix((Fs * segment_duration)+1);
            num_segments = ceil(length(data) / len_segment);
            ham_win=(hamming(len_segment))';
            % Initializing segments matrix
            segments = zeros(num_segments, len_segment);

            for i = 1:num_segments
                % Determine the start and end indices for the current segment
                start_idx = (i - 1) * len_segment + 1;
                end_idx = min(start_idx + len_segment - 1, length(data));
                segment = data(start_idx:end_idx);

                if length(segment) < len_segment
                    segment = [segment, zeros(1, len_segment - length(segment))]; %zero padding if needed
                end

                segment=segment .* ham_win; %comment out if unnecessary
                segment = (segment - mean(segment)) / std(segment); %normalizing
                segments(i, :) = segment;
            end


            features=find_features(app,segments,Fs);

            pdct=predict(mdl,features); % it has column vector of predicted labels
            if nnz(pdct)>length(pdct)
                app.EditField_2.Value = "Patient have Parkinson Symptomps";
            else
                app.EditField_2.Value = "Person is normal";
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartRecordButton = uibutton(app.UIFigure, 'push');
            app.StartRecordButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordButtonPushed, true);
            app.StartRecordButton.Text = 'Start Record';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.VoiceTab = uitab(app.TabGroup);
            app.VoiceTab.Title = 'Voice';
            app.voice = uiaxes(app.VoiceTab);
            title(app.voice, 'Voice Signal')
            xlabel(app.voice, 'time(s)')
            ylabel(app.voice, 'Amplitude')
            zlabel(app.voice, 'Z')
            app.SpectrumTab = uitab(app.TabGroup);
            app.SpectrumTab.Title = 'Spectrum';
            app.spec = uiaxes(app.SpectrumTab);
            title(app.spec, 'Single-Sided Amplitude Spectrum of y(t)')
            xlabel(app.spec, 'Frequency (Hz)')
            ylabel(app.spec, '|Y(f)|')
            zlabel(app.spec, 'Z')
            app.FilterTab = uitab(app.TabGroup);
            app.FilterTab.Title = 'Filter';
            app.filter_mag = uiaxes(app.FilterTab);
            title(app.filter_mag, 'Magnitude Response of the Filter')
            xlabel(app.filter_mag, 'Frequency (Hz)')
            ylabel(app.filter_mag, 'Magnitude')
            zlabel(app.filter_mag, 'Z')
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.Lamp = uilamp(app.UIFigure);
            app.status = uieditfield(app.UIFigure, 'text');
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = signals_project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end